classdef gravity_assist_simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        EnergygainEditField       matlab.ui.control.NumericEditField
        EnergygainEditFieldLabel  matlab.ui.control.Label
        Y_dotEditField            matlab.ui.control.NumericEditField
        Y_dotEditFieldLabel       matlab.ui.control.Label
        Y0EditField               matlab.ui.control.NumericEditField
        Y0EditFieldLabel          matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        RunButton                 matlab.ui.control.Button
        MassofcraftSlider         matlab.ui.control.Slider
        MassofcraftSliderLabel    matlab.ui.control.Label
        VelocityofPlanetSlider    matlab.ui.control.Slider
        VelocityofPlanetLabel     matlab.ui.control.Label
        RadiusSlider              matlab.ui.control.Slider
        RadiusSliderLabel         matlab.ui.control.Label
        MSlider                   matlab.ui.control.Slider
        MSliderLabel              matlab.ui.control.Label
        GSlider                   matlab.ui.control.Slider
        GSliderLabel              matlab.ui.control.Label
        X_dotEditField            matlab.ui.control.NumericEditField
        X_dotEditFieldLabel       matlab.ui.control.Label
        X0EditField               matlab.ui.control.NumericEditField
        X0EditFieldLabel          matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes2_3                 matlab.ui.control.UIAxes
        UIAxes2_2                 matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        G = 1; % Gravity accerelation constant
        M = 100 % Mass of Planet
        radius = 1 % Radius of Planet
        vel = 2 % Velocity of Planet
        m = 1 % Mass of spacecraft
        time_range = [0 20];
        X0 = [0; 0]; % The initial position of planet
        R = [10; -5]; % The initial distance from planet to spacecraft
        R_dot = [-4; 0] % The initial velocity of spacecraft
        dt = 1.e-1; % Time rate
        ang = linspace(0,2*pi,100); % Angle for drawing circle
    end
    
    methods (Access = private)
        
        function dxdv = dydx(app,X)
            x = X(1:2);
            v = X(3:4);
            dx = v;
            dv = -(app.G.*app.M)./(norm(x))^3.*x;
            dxdv = [dx; dv];
        end
        
        function X_next = RK4(app,X,dt)
            K1 = app.dt.*dydx(app,X);
            K2 = app.dt.*dydx(app,X+K1./2);
            K3 = app.dt.*dydx(app,X+K2./2);
            K4 = app.dt.*dydx(app,X+K3);
        
            X_next = X + (K1 +2.*K2 +2.*K3 +K4)./6;
        end

        function cond = condition(app,X)
            if norm(X(1:2)) <= app.radius
                cond = true;
                msgbox("Collapse");
            else
                cond = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: X0EditField
        function X0EditFieldValueChanged(app, event)
            % X0 position
            value = app.X0EditField.Value;
            app.R(1)= value;
        end

        % Value changed function: Y0EditField
        function Y0EditFieldValueChanged(app, event)
            % Y0 position
            value = app.Y0EditField.Value;
            app.R(2)= value;
        end

        % Value changed function: X_dotEditField
        function X_dotEditFieldValueChanged(app, event)
            % X_dot(x component velocity)
            value = app.X_dotEditField.Value;
            app.R_dot(1) = value;
        end

        % Value changed function: Y_dotEditField
        function Y_dotEditFieldValueChanged(app, event)
            % Y_dot (y component velocity)
            value = app.Y_dotEditField.Value;
            app.R_dot(2) = value;
        end

        % Value changing function: GSlider
        function GSliderValueChanging(app, event)
            % G: gravity constant
            changingValue = event.Value;
            app.G = changingValue;
        end

        % Value changing function: MSlider
        function MSliderValueChanging(app, event)
            % Mass of planet
            changingValue = event.Value;
            app.M = changingValue;
        end

        % Value changing function: RadiusSlider
        function RadiusSliderValueChanging(app, event)
            % Radius of planet
            changingValue = event.Value;
            app.radius = changingValue;
        end

        % Value changing function: VelocityofPlanetSlider
        function VelocityofPlanetSliderValueChanging(app, event)
            % velocity of planet
            changingValue = event.Value;
            app.vel = changingValue;
        end

        % Value changing function: MassofcraftSlider
        function MassofcraftSliderValueChanging(app, event)
            changingValue = event.Value;
            app.m = changingValue;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Animated line
            traj = animatedline(app.UIAxes,"Color",'b');
            circle = animatedline(app.UIAxes,"Color",'k');
            phase_x = animatedline(app.UIAxes2,"Color",'b');
            phase_y = animatedline(app.UIAxes2_2,"Color",'r');
            energy = animatedline(app.UIAxes2_3,"Color",'g');
            % Initialize the figure
            app.UIAxes.XLim = [-100,100];
            app.UIAxes.YLim = [-100,100];
            app.UIAxes2_3.XLim = app.time_range;
            % Play the simulator
            % Trajectory
            X = [app.R; app.R_dot]; % vector of position and velocity
            for t = app.time_range(1):app.dt:app.time_range(end)
                clearpoints(circle);
                x0 = [app.vel.*t; 0];
                %%% draw planet
                xc = app.radius.*cos(app.ang) + x0(1);
                yc = app.radius.*sin(app.ang) + x0(2);
                addpoints(circle,xc,yc);
                %%% draw trajectory
                X = RK4(app,X,app.dt);
                if condition(app,X)
                    break
                end
                X_space = X(1:2) + x0;
                V_space = X(3:4) + [app.vel,0];
                addpoints(traj,X_space(1),X_space(2));

                %%% draw phase diagram
                addpoints(phase_x,X_space(1),V_space(1));
                addpoints(phase_y,X_space(2),V_space(2));

                %%% draw total energy graph
                E = norm(V_space).^2./2 - app.G.*app.M./norm(X(1:2));
                addpoints(energy,t,E);

                %%% Update
                drawnow limitrate
                pause(0.05)
            end
            [time,Energy] = getpoints(energy);
            app.EnergygainEditField.Value = Energy(end) - Energy(1);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Initialize the constant
            app.R = [10; -5];
            app.X0EditField.Value = 10;
            app.Y0EditField.Value = -5;
            app.R_dot = [-4; 0];
            app.X_dotEditField.Value = -4;
            app.Y_dotEditField.Value = 0;
            app.G = 1;
            app.GSlider.Value = 1;
            app.M = 100;
            app.MSlider.Value = 100;
            app.radius = 1;
            app.RadiusSlider.Value = 1;
            app.vel = 2;
            app.VelocityofPlanetSlider.Value = 2;
            app.m = 1;
            app.MassofcraftSlider.Value = 1;
            app.EnergygainEditField.Value = 0;

            % Clear the figure
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {711, 711};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {208, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.X0EditFieldLabel = uilabel(app.LeftPanel);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.X0EditField.ValueChangedFcn = createCallbackFcn(app, @X0EditFieldValueChanged, true);
            app.X0EditField.Value = 10;
            app.X_dotEditFieldLabel = uilabel(app.LeftPanel);
            app.X_dotEditFieldLabel.Text = 'X_dot';
            app.X_dotEditField = uieditfield(app.LeftPanel, 'numeric');
            app.X_dotEditField.ValueChangedFcn = createCallbackFcn(app, @X_dotEditFieldValueChanged, true);
            app.X_dotEditField.Value = -4;
            app.GSliderLabel = uilabel(app.LeftPanel);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.LeftPanel);
            app.GSlider.Limits = [0 5];
            app.GSlider.ValueChangingFcn = createCallbackFcn(app, @GSliderValueChanging, true);
            app.GSlider.Value = 1;
            app.MSliderLabel = uilabel(app.LeftPanel);
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.LeftPanel);
            app.MSlider.Limits = [100 1000];
            app.MSlider.ValueChangingFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.MSlider.Value = 100;
            app.RadiusSliderLabel = uilabel(app.LeftPanel);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.LeftPanel);
            app.RadiusSlider.Limits = [0 10];
            app.RadiusSlider.ValueChangingFcn = createCallbackFcn(app, @RadiusSliderValueChanging, true);
            app.RadiusSlider.Value = 1;
            app.VelocityofPlanetLabel = uilabel(app.LeftPanel);
            app.VelocityofPlanetLabel.Text = {'Velocity'; 'of Planet'};
            app.VelocityofPlanetSlider = uislider(app.LeftPanel);
            app.VelocityofPlanetSlider.Limits = [1 11];
            app.VelocityofPlanetSlider.ValueChangingFcn = createCallbackFcn(app, @VelocityofPlanetSliderValueChanging, true);
            app.VelocityofPlanetSlider.Value = 2;
            app.MassofcraftSliderLabel = uilabel(app.LeftPanel);
            app.MassofcraftSliderLabel.Text = {'Mass'; 'of craft'};
            app.MassofcraftSlider = uislider(app.LeftPanel);
            app.MassofcraftSlider.Limits = [1 11];
            app.MassofcraftSlider.ValueChangingFcn = createCallbackFcn(app, @MassofcraftSliderValueChanging, true);
            app.MassofcraftSlider.Value = 1;
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Y0EditFieldLabel = uilabel(app.LeftPanel);
            app.Y0EditFieldLabel.Text = 'Y0';
            app.Y0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Y0EditField.ValueChangedFcn = createCallbackFcn(app, @Y0EditFieldValueChanged, true);
            app.Y0EditField.Value = -5;
            app.Y_dotEditFieldLabel = uilabel(app.LeftPanel);
            app.Y_dotEditFieldLabel.Text = 'Y_dot';
            app.Y_dotEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Y_dotEditField.ValueChangedFcn = createCallbackFcn(app, @Y_dotEditFieldValueChanged, true);
            app.EnergygainEditFieldLabel = uilabel(app.LeftPanel);
            app.EnergygainEditFieldLabel.Text = 'Energy gain';
            app.EnergygainEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'trajectory')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'x-phase diagram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'X''')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'y-phase diagram')
            xlabel(app.UIAxes2_2, 'Y')
            ylabel(app.UIAxes2_2, 'Y''')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.RightPanel);
            title(app.UIAxes2_3, 'Total Energy graph')
            xlabel(app.UIAxes2_3, 'time(s)')
            ylabel(app.UIAxes2_3, 'Energy')
            zlabel(app.UIAxes2_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gravity_assist_simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end